classdef app1GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        GPSButton              matlab.ui.control.Button
        UploadButton           matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        No_stepsTextArea       matlab.ui.control.TextArea
        No_stepsTextAreaLabel  matlab.ui.control.Label
        UIAxes2_2              matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %verfica numarul de pasi

% Use FINDPEAKS to determine the local maxima.
        [~,~,~,accel]=read(); 
        minPeakHeight = std(accel);
        [pks, locs] = findpeaks(accel, 'MINPEAKHEIGHT', minPeakHeight);

%The number of steps taken is the number of peaks:
app.No_stepsTextArea.Value=numel(pks);

        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
             global go;
             go=true;
           while(go)
     %Read data from ThingSpeak Cloud          
            [lat,lon,alt,acc]=read();
     % put a pause on taking the data due to ThingSpeak limitations
            pause(15);

     %define projected coordinate reference system object (CRS)
     %introduce ESPG code for Cluj-Napoca, Romania(3857)
            proj = projcrs(3857);

     %transform the latitude-longitude coordinates specified by lat 
     % and lon to the x and y map coordinates in the projected 
     % coordinate reference system specified by proj
            
            [x,y]=projfwd(proj,lat,lon);

            plot(app.UIAxes,x)
            plot(app.UIAxes2,y)
            plot(app.UIAxes2_2,alt)
            stem(app.UIAxes_2,acc)

           
            end
        end

        % Button pushed function: GPSButton
        function plot_gps_app(app, event)
          [lat,lon,alt]=read(); 
         plot_gps(lat, lon, alt);   
        end

        % Callback function: No_stepsTextArea, RightPanel
        function No_stepsEditFieldValueChanged(app, event)
            value = app.No_stepsEditField.Value;            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {110, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UploadButton = uibutton(app.LeftPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.GPSButton = uibutton(app.LeftPanel, 'push');
            app.GPSButton.ButtonPushedFcn = createCallbackFcn(app, @plot_gps_app, true);
            app.GPSButton.Text = 'GPS';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @No_stepsEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Latitude')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Longitude')
            xlabel(app.UIAxes2, 'Date')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Acceleration')
            xlabel(app.UIAxes_2, 'Date')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            title(app.UIAxes2_2, 'Altitude')
            xlabel(app.UIAxes2_2, 'Date')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.No_stepsTextAreaLabel = uilabel(app.RightPanel);
            app.No_stepsTextAreaLabel.Text = 'No_steps:';
            app.No_stepsTextArea = uitextarea(app.RightPanel);
            app.No_stepsTextArea.ValueChangedFcn = createCallbackFcn(app, @No_stepsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end